classdef segm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        alphax1000SliderLabel          matlab.ui.control.Label
        alphax1000Slider               matlab.ui.control.Slider
        mux0001SliderLabel             matlab.ui.control.Label
        mux0001Slider                  matlab.ui.control.Slider
        RunButton                      matlab.ui.control.Button
        sigmaEditFieldLabel            matlab.ui.control.Label
        sigmaEditField                 matlab.ui.control.EditField
        NumberofcontoursSpinnerLabel   matlab.ui.control.Label
        NumberofcontoursSpinner        matlab.ui.control.Spinner
        TruncatefcnCheckBox            matlab.ui.control.CheckBox
        TextAlphaValue                 matlab.ui.control.TextArea
        TextMuValue                    matlab.ui.control.TextArea
        epsilonSliderLabel             matlab.ui.control.Label
        epsilonSlider                  matlab.ui.control.Slider
        TextEpsilon                    matlab.ui.control.TextArea
        ShowcontoursLabel              matlab.ui.control.Label
        ShowcontoursListBox            matlab.ui.control.ListBox
        DashedCheckBox                 matlab.ui.control.CheckBox
        ShowevolCheckBox               matlab.ui.control.CheckBox
        kmaxSpinnerLabel               matlab.ui.control.Label
        kmaxSpinner                    matlab.ui.control.Spinner
        IterationsEditFieldLabel       matlab.ui.control.Label
        IterationsEditField            matlab.ui.control.EditField
        commentEditFieldLabel          matlab.ui.control.Label
        commentEditField               matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        RunTimeTextAreaLabel           matlab.ui.control.Label
        RunTimeTextArea                matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        InstructionsTextArea           matlab.ui.control.TextArea
        CloseButton                    matlab.ui.control.Button
        TotalTimeTextAreaLabel         matlab.ui.control.Label
        TotalTimeTextArea              matlab.ui.control.TextArea
        MeanIntensititesTextAreaLabel  matlab.ui.control.Label
        MeanIntensititesTextArea       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        data
        pt = .01; %pause time (for fflush)
        done = 0;
        handlearray;
        colormaphot;
                
    end
    
    methods 
        function plot_results(app)
            %imagesc(app.UIAxes_2,app.data.gcurr); 
            %{
            hold(app.UIAxes_2,"on");
            colormap(app.UIAxes_2,gray);
            colorarray = ['r'; 'g'; 'b'; 'y'; 'm'; 'c'; 'w'];
            for i=1:size(app.data.par.l,1)
                contour(app.UIAxes_2,app.data.phi-app.data.par.l(i), [0 0], colorarray(i), 'LineWidth', .25,...
                    'DisplayName', num2str(round(app.data.c(i+1),0)));
            end
            hold(app.UIAxes_2,"off");
            %}
            %lng = legend(app.UIAxes_2, 'show', 'Location', 'northeastoutside');
            %hLegend = findobj(gcf, 'Type', 'Legend');
            %lng.ItemTokenSize = [10,30];
            %lng.Color = [.6 .6 .6];
            %lng.Title.String = ['Mean' newline 'intensities'];
            
            %app.Image.
            
            par=app.data.par;
            f=app.data.phi;
            c=app.data.c;
            l=par.l;
            e=par.eps;
            g_ap = c(1).*app.hvi(e,l(1)-f) + c(par.nlevels+1).*app.hvi(e,f-l(par.nlevels));
            for i=2:par.nlevels
               g_ap = g_ap + c(i).*app.hvi(e,f-l(i-1)).*app.hvi(e,l(i)-f); 
            end
            imagesc(app.UIAxes_3,g_ap, [0 255]);colormap(app.UIAxes_3,gray);
            
            mesh(app.UIAxes_4,app.data.phi);colormap(app.UIAxes_4,app.colormaphot)
            
            str = "";
            for i=1:size(c)
                str=str + [num2str(round(c(i),2)) newline];
            end
            app.MeanIntensititesTextArea.Value = str;
            
            ShowcontoursListBoxValueChanged(app);
        end
        
        function app = pre_process(app)

            I=app.data.I;
            if (size(I,3)==3)
                BW = rgb2gray(I);
            else
                BW = I;
            end
            
            color = 'black';
            
            if (isequal(size(BW),[576,768]))
                NT = insertShape(BW,'FilledRectangle', [0 0 768 28], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [550 60 135 22], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [550 82 210 40], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [550 122 180 20], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [570 495 180 80], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [570 495 180 80], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 110 65 360], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [213 28 345 16], 'color', color, 'opacity', 1);
            elseif (isequal(size(BW),[480,640]))
                NT = insertShape(BW,'FilledRectangle', [0 0 180 100], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 0 640 25], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 455 640 25], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [445 400 200 70], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [430 80 135 80], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [430 60 122 20], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [430 80 210 40], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 0 64 420], 'color', color, 'opacity', 1);
            elseif(isequal(size(BW),[1080,1920]))
                NT = BW(10:369, 10:489);
                NT = insertShape(NT,'FilledRectangle', [0 0 480 35], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 0 50 360], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [0 340 480 20], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [320 0 160 120], 'color', color, 'opacity', 1);
                NT = insertShape(NT,'FilledRectangle', [330 300 150 60], 'color', color, 'opacity', 1);
            end
            
            NT = rgb2gray(NT);
            
            app.data.g0 = double(NT);
            
            
        end
        
        function app = initialize(app)

            %% scaling
            g0=app.data.g0;
            ScaleFactor = 255/max(g0(:));
            g0 = g0.*ScaleFactor;
            g0 = g0.*(g0<254);
            
            g0 = imgaussfilt(g0, app.data.par.sigma);
            app.data.g0_filt=g0;
            %% create multiple resolutions
            %app.data.par.kMax = 3;%round(log2(sum(size(g0)/100)/2));
            app.data.res.k = app.data.par.kMax;
            [app.data.g, app.data.gOffset] = app.create_multiple_resolutions(g0,app.data.res.k);
            app.data.gcurr = app.data.g{app.data.res.k+1};
            
            %% initial contour
            switch app.data.par.nphi
                case 1
                    app = drawmask(app, size(app.data.gcurr)); 
                    app.data.phi = app.reinitialize(app.data.mask_0);
                    app.data.phi_prev = app.data.phi;
                    
                case 2
                    for i=1:app.data.par.nphi
                        app = drawmask(app, size(app.data.gcurr), i); 
                        app.data.phi{i} = app.reinitialize(app.data.mask);
                        app.data.phi_prev{i} = app.data.phi{i};
                    end
            end
            
            app = calc_c(app);
            
            app.data.count.steps = zeros(app.data.par.kMax+1,1);
            app.data.count.it = zeros(app.data.par.kMax+1,1);
            
        end
        
        function app = construct_data(app,type)
            app.data.g0 = [];
            app.data.type = type;
            app.data.it.stepsvec=[2; 2; 50; 50;50];
            app.data.it.itervec=[1; 1; 20; 20; 20];           
            app.data.par = app.parameters(type);           
        end     

        function app = standard(app)
            app.data.it.steps = app.data.it.stepsvec(app.data.par.kMax+1);
            app.data.it.iter = app.data.it.itervec(app.data.par.kMax+1);
            for i = app.data.par.kMax:-1:1
                app = chanvese_evolve(app);
                app = change_resolution_phi(app, 1);
                disp(['k is now ' num2str(app.data.res.k)]);
                app.data.it.iter = app.data.it.itervec(i);
                app.data.it.steps=app.data.it.stepsvec(i);
            end
            app = chanvese_evolve(app);
        end
        
        function app = calc_c(app) 
            f=app.data.phi;
            g=app.data.gcurr;
            l=app.data.par.l;
            e=app.data.par.eps;                            
            nlevels = app.data.par.nlevels;
            c=zeros(nlevels+1,1);
            c(1)=sum(g.*app.hvi(e,l(1)-f),'all')/sum(app.hvi(e,l(1)-f),'all'); %outside
            for i=2:nlevels
               c(i)=sum(g.*app.hvi(e,f-l(i-1)).*app.hvi(e,l(i)-f),'all')/sum(app.hvi(e,f-l(i-1)).*app.hvi(e,l(i)-f),'all');
            end
            c(nlevels+1)=sum(g.*app.hvi(e,f-l(nlevels)),'all')/sum(app.hvi(e,f-l(nlevels)),'all'); %inside
            
            app.data.c=c;
        end
        
        function app = change_resolution_phi(app, refine)

            k = app.data.res.k;
            
            % detect when k hits the boundaries, (0, kMax), spare the updates
            DoOperation = (refine && (k>0)) || (~refine && (k < app.data.kMax));
            if ~DoOperation
                disp('Not possible');
                return
            end
            
            % increment/decrement K (increase/decrease resolution level)
            delta_k = ~refine*2 - 1;
            k_next = k + delta_k;
            
            % --- actual resolution change ---
            % Full, 0-protect in Down (ignored), only used in Up
            offset = app.data.gOffset(max(k,1),:);
            
            switch app.data.par.nphi
                case 1
                     phi_pr = app.resolution_op(app.data.phi, refine, offset);
                    %data.phi_prev = resolution_op(data.phi_prev, refine, offset);
                    app.data.gcurr = app.data.g{k_next+1}; % chk only...
            
                    % preserve gradient values...
                    %data.phi = phi_pr * 2^(-delta_k); % 2 when Up, 1/2 when Down
                    app.data.phi = phi_pr;
                    
                case 2
                    for i=1:2
                        phi_pr = app.resolution_op(app.data.phi{i}, refine, offset);
                        %data.phi_prev = resolution_op(data.phi_prev, refine, offset);
                        app.data.gcurr = app.data.g{k_next+1}; % chk only...
            
                        % preserve gradient values...
                        app.data.phi{i} = phi_pr * 2^(-delta_k); % 2 when Up, 1/2 when Down
                        %data.phi{i} = phi_pr;
                    end
            
            end
            app = calc_c(app);
            app.data.res.k = k_next;
            
        end
        
        function app = chanvese_evolve(app)
            par=app.data.par;
            g = app.data.gcurr;
            k = app.data.res.k;
            thisRange = (max(g(:))-min(g(:)))^2;  
            thismu = par.mu * thisRange *2^(-k);
            for i = 1:app.data.it.steps
                app = phi_next(app,thismu);
                if (app.ShowevolCheckBox.Value == true)
                    RefreshButtonPushed(app);pause(app.pt);
                end
                app = calc_c(app); 
            end            
        end
        
        function app = drawmask(app, size)

            type=app.data.par.masktype;
            mask_0=zeros(size);
            mask_xc = round(size(1)/2);
            mask_yc = round(size(2)/2);
            
            if type == "circle"
                
                R = round(.4*min(size));
                
                mask = app.DrawCircle(R);
                
                mask_0(mask_xc-R:mask_xc+R, mask_yc-R:mask_yc+R) = mask;
            
            elseif type == "rectangle"
               
                R=round(.8*size(1)/2);
                C=round(.8*size(2)/2);
                mask = ones(R*2+1, C*2+1);    
                mask_0(mask_xc-R:mask_xc+R, mask_yc-C:mask_yc+C) = mask;
                
            elseif type == "multicircles"
                
                R_singlemask = 15;
                R_circle = round(.3*R_singlemask);
                
                n_circles_column = floor(size(1)/(R_singlemask));
                n_circles_line = floor(size(2)/(R_singlemask)); 
                
                circle = app.DrawCircle(R_circle);
                
                center = round(R_singlemask/2); %center of singlemask
                
                singlemask = zeros(R_singlemask);
                %single_mask
                singlemask(center-R_circle:center+R_circle, center-R_circle:center+R_circle) = circle;
            
                R=n_circles_column*R_singlemask;
                C=n_circles_line*R_singlemask;
                mask=zeros(R,C);
                for i=1:n_circles_line
                    for j=1:n_circles_column
                        mask((1+(j-1)*R_singlemask):(j*R_singlemask),(1+(i-1)*R_singlemask):(i*R_singlemask))= singlemask;
                    end
                end
                
                if rem(size(1),2)~=0, mask_xc=(size(1)-1)/2; end
                if rem(size(2),2)~=0, mask_yc=(size(2)-1)/2; end
                mask_0(mask_xc-R/2+1:mask_xc+R/2, mask_yc-C/2+1:mask_yc+C/2) = mask;
            end
            
            app.data.mask_0=mask_0;
        end   
        
        function app = phi_next(app, mu)

            fn=app.data.phi;
            g=app.data.gcurr;
            c=app.data.c;
            iter=app.data.it.iter;
            dt=app.data.par.dt;
            par=app.data.par;
            e=par.eps;
            
            sigma=1;
            G=fspecial('gaussian',1,sigma); % Caussian kernel
            Img_smooth=conv2(g,G,'same');  % smooth image by Gaussiin convolution
            [Ix,Iy]=gradient(Img_smooth);
            f_edge=Ix.^2+Iy.^2;
            edge_fcn=1./(1+f_edge);  % edge indicator function.
            
            switch app.data.type        
                case "nregions"
                    
                    l=par.l;
                    lambda=par.lambda;
                    nlevels = size(l,1);
                    
                    WorkingClass = class(fn);  
                    [nR,nC] = size(fn);
            
                    Th_R = mu*app.abs_2layers(app,fn, 1); 
                    Th_C = mu*app.abs_2layers(app,fn, 2);
                   
                    alpha = zeros(nR,nC, WorkingClass); 
            
                    alpha(2:nR-1,:) = Th_R(1:nR-2,:) + Th_R(2:nR-1,:);   
                    alpha(:,2:nC-1) = alpha(:,2:nC-1) + Th_C(:,1:nC-2) + Th_C(:,2:nC-1);    
            
                    dirac=0;
                    for a=1:size(l,1)
                        dirac=dirac+app.delta(e,fn-l(a));
                    end
                        
                    for i = 1:iter
            
                        beta = zeros(nR,nC, WorkingClass);
                        
                        beta(2:nR-1,:) = Th_R(1:nR-2,:).*fn(1:nR-2,:) + Th_R(2:nR-1,:).*fn(3:nR,:); % dX
                        beta(:,2:nC-1) = beta(:,2:nC-1) + Th_C(:,1:nC-2).*fn(:,1:nC-2) + Th_C(:,2:nC-1).*fn(:,3:nC);  % dY
                    
                        area_part = lambda(1).*app.delta(e,l(1)-fn).*(g-c(1)).^2;
                        for i=2:nlevels
                            area_part = area_part +lambda(i).*(...
                                        + app.delta(e,l(i)-fn).*app.hvi(e,fn-l(i-1)).*(g-c(i)).^2 ... 
                                        - app.delta(e,fn-l(i-1)).*app.hvi(e,l(i)-fn).*(g-c(i)).^2);
                        end
                        area_part = area_part - lambda(numel(lambda)).*app.delta(e,fn-l(nlevels)).*(g-c(nlevels+1)).^2;
                        
                        if (par.edgeStopType == "inner")
                            edgeStopType = par.a/nlevels.*app.delta(e,fn-l(nlevels)).*edge_fcn;
                        elseif (par.edgeStopType == "all")
                            edgeStopType = par.a.*dirac.*edge_fcn;
                        elseif (par.edgeStopType == "extremes")
                            edgeStopType = par.a/2.*(app.delta(e,fn-l(nlevels))+app.delta(e,fn-l(1))).*edge_fcn;
                        end
                        
                        area_part = area_part - edgeStopType;
                        
                        fn = (fn + (dirac*dt) .*(beta + area_part)) ./ (1 + (dirac*dt) .* alpha);
                     
                        % --- borders and corners
                        fn(:,1) = fn(:,2);
                        fn(:,size(fn,2)) = fn(:,size(fn,2)-1);
                        fn(1,:) = fn(2,:);
                        fn(size(fn,1),:) = fn(size(fn,1)-1,:);
            
                        if par.truncate == true
                            fn = app.minvaluecheck(fn, par.truncateval);
                        end
                    end
                    
                    
            end
            sigma=1;
            G=fspecial('gaussian',1,sigma); % Caussian kernel
            fn=conv2(fn,G,'same');  % smooth image by Gaussiin convolution
            app.data.phi=fn;
        end
        
    end
    
    methods(Static)
        function [g, G_isodd_dim] = create_multiple_resolutions(g0, kMax)
            kMax=4;
            g{1} = g0; clear g0;
            G_isodd_dim = zeros(kMax,2);
            for i = 2:kMax+1
                [nR, nC] = size(g{i-1});
                G_isodd_dim(i-1,:) = mod([nR nC],2); % offsets used in Fn Multi-Scale
                ixR = 1:2:nR; ixC = 1:2:nC;
                nextSmSlice = interp2(g{i-1}, ixC, ixR', 'spline');
                g{i} = nextSmSlice; 
            end
        end
        
        function mask = DrawCircle(R)
        
            R=R+1; %force odd
            
            %draw a quarter of the circle
            dc = .5 + (0:R-1); %distances of centers of pixels
            xc = repmat(dc, R, 1);
            yc = repmat(dc', 1, R);
            dc = sqrt(xc.^2 + yc.^2);
            mask = dc<=R;
            
            %create whole circle(by flipping 1/4 circle)
            mask = [flipud(mask); mask];
            mask = [fliplr(mask) mask];
            
            %get (R+1)th column in the middle
            mask = mask(([(1:R),(R+2:size(mask,1))]),:);   %delete (R+1)-th row
            mask = mask(:,([(1:R),(R+2:size(mask,2))]));   %delete (R+1)-th column
            
            %R=R-1;
        end
        
        function f = minvaluecheck(f, minacceptvalue)
            
            newvaluemin = -10;
            newvaluemax=100;
            maxacceptvalue=100;
            
            f(f<minacceptvalue)=newvaluemin;
            f(f>maxacceptvalue)=newvaluemax;
                        
        end
        
        function fn = reinitialize(mask)
            mask=mask>0;
            fn = -bwdist(mask,'euclidean');
            % also do the inside!!!
            temp = bwdist(~mask,'euclidean');
            fn(mask) = temp(mask);
            clear temp
        end
        
        function [g_o, offset] = resolution_op(g, refine, offset)
        
            [nR, nC] = size(g);
            %% figure out input type, match output (logical, for masks)
            SetLogicalOutput = false;
            if islogical(g) % will force output to logical
                g = single(g);
                SetLogicalOutput = true;
            end
            
            method = 'vertex-full';
            if refine
                % k>2k, k+1>2k+1
                % double the size, then if offsets remove at end
                nR_o = 2*nR; nC_o = 2*nC; 
                % init the right size g_o matrix
                g_o = zeros(nR_o, nC_o, class(g)); % normally single :-)
                switch method            
                    case 'vertex-full' % [1 2 1; 2 4 2; 1 2 1]/4
                        nRC = size(g);
                        ForceEven = ~offset;
                        nRCo = 2*nRC;
                        % natural: k->2k-1, if ForceEven then ADD a row/col at end
                          % init the right size g_o matrix
                        g_o = zeros(nRCo);
                        w = [1/2 1/4]; % H/V and Diagonal
                                    
                        ixEvenRow = 2:2:nRCo(1);
                        ixOddRow = ixEvenRow-1;
                        ixEvenCol = 2:2:nRCo(2);
                        ixOddCol = ixEvenCol-1;
                                   
                        % ODD-ODD will be injected
                        % init with g in Odd-Odd
                        g_o(ixOddRow, ixOddCol) = g;
                        % --- V/H contributions
                        g_w = g*w(1);
                        % distribute it properly, LRUD
            
                        % --- DOWN --- init EVEN dest ROWS with ALL g_w Rows 
                        % ... only even cols :-) cheaper
                        g_o(ixEvenRow(1:nR-1),ixOddCol) = g_w(1:nR-1,:);
                        if ForceEven(1) % i.e. nRo even = 2*nR
                            g_o(2*nR,ixOddCol) = 2*g_w(nR,:); % or g(nR,:);
                        end
                        % --- UP --- add ALL g_w Rows to ODD dest rows            
                        g_o(ixEvenRow(1:nR-1),ixOddCol) = ...
                            g_o(ixEvenRow(1:nR-1),ixOddCol) + g_w(2:nR,:);
                        
                        % reset moving matrix for columns :-)
                        g_w = g_o(:,ixOddCol)*w(1); % only works for 1/4 = 1/2*1/2...
                        
                        % --- LEFT --- + src COLs 2:end to EVEN dest COLs 1:end-1            
                        g_o(:,ixEvenCol(1:nC-1)) = g_o(:,ixEvenCol(1:nC-1))+...
                            g_w(:,2:nC);            
                        % --- RIGHT --- + src COLs 1:end-1 to EVEN dest COLs 2:end
                        g_o(:,ixEvenCol(1:nC-1)) = g_o(:,ixEvenCol(1:nC-1))+...
                            g_w(:,1:nC-1);
                        if ForceEven(2) % i.e. nCo even = 2*nC
                            g_o(:,2*nC) = 2*g_w(:,nC);
                        end
                end
                % REMOVE row/col at end when signalled by offset
                if offset(1), g_o = g_o(1:nR_o-1,:); end
                if offset(2), g_o = g_o(:, 1:nC_o-1); end
                
            else % restrict!
                nRC = size(g);
                offset = mod(nRC,2);
                
                switch method
            
                    case 'vertex-full' % [1 2 1; 2 4 2; 1 2 1]/16
                        nRC_even = floor(nRC/2);
                        nRC_odd = nRC_even + offset;
                        w = [4 2 1]/16;
            
                        ixER = 2:2:nRC(1); % always of size floor(nR/2)
                        ixEC = 2:2:nRC(2);
                        ixOR = 1:2:nRC(1); % of size floor(nR/2) + offset_R
                        ixOC = 1:2:nRC(2);
            
                        % --- weigh ALL g! ---
                        g_w = zeros(nRC);
                        % 1 -> keep ODD-ODD
                        g_w(ixOR,ixOC) = w(1)*g(ixOR,ixOC);
                        % 2 -> ,[EVEN,ODD], [ODD,EVEN]
                        g_w(ixER,ixOC) = w(2)*g(ixER,ixOC);
                        g_w(ixOR,ixEC) = w(2)*g(ixOR,ixEC);
                        % 3 -> diagonal el-s, [EVEN,EVEN]
                        g_w(ixER,ixEC) = w(3)*g(ixER,ixEC);
                        
                        % +(even)ROWS, UD
                        % ---------------
                        % DOWN, weigh double the 2nd
                        g_w(1,:) = g_w(1,:) + 2*g_w(2,:); % 2 to 1
                        % 2k to 2k-1
                        ixThis = ixER(2:nRC_even(1))-1;
                        g_w(ixThis,:) = g_w(ixThis,:) + g_w(ixThis+1,:);
                        % UP, weigh double next to last row if nR is odd
                        ixThis = ixOR(2:nRC_odd(1)-1);
                        g_w(ixThis,:) = g_w(ixThis,:) + g_w(ixThis-1,:);
                        % next to last row
                        ixThis = ixOR(nRC_odd(1));
                        g_w(ixThis,:) = g_w(ixThis,:) + (1+offset(1))*g_w(nRC(1),:);
            
                        % discard Even Rows
                        g_w = g_w(ixOR,:);
                        
                        % +(odd)COLS, LR
                        % ---------------
                        % DOWN, weigh double the 2nd COL
                        g_w(:,1) = g_w(:,1) + 2*g_w(:,2); % 2 to 1
                        % 2k to 2k-1
                        ixThis = ixEC(2:nRC_even(2))-1;
                        g_w(:,ixThis) = g_w(:,ixThis) + g_w(:,ixThis+1);
                        % UP, weigh double next to last col if nC is odd
                        ixThis = ixOC(2:nRC_odd(2)-1);
                        g_w(:,ixThis) = g_w(:,ixThis) + g_w(:,ixThis-1);
                        % next to last COL
                        ixThis = ixOC(nRC_odd(2));
                        g_w(:,ixThis) = g_w(:,ixThis) + (1+offset(2))*g_w(:,nRC(2));
                        
                        % discard Even Cols
                        g_o = g_w(:,ixOC);
                        
                    case 'vertex-injection'
                        g_o = g(1:2:nR,1:2:nC);
                end
            
            end
            
                if SetLogicalOutput
                    g_o = g_o >= 0.5;
                end
                    
        
        end
        
        function Hvi = hvi(eps,f)
            Hvi=.5+1/pi*(atan(f/eps));
        end
        
        function Dirac = delta(eps,f)
            Dirac = eps./(pi*(eps^2+(f).^2));
        end
        
        function df = finite_diff(f, xory, method) %finite differences

            df = zeros(size(f,1), size(f,2));
            
            switch method
                
                case 'f' %forward
                    if xory == 'x'
                        df(:,1:size(f,2)-1) = f(:,2:size(f,2)) - f(:,1:size(f,2)-1);
                        df(:,size(f,2)) = f(:,size(f,2)) - f(:,size(f,2)-1);
                    elseif xory == 'y'
                        df(1:size(f,1)-1,:) = f(2:size(f,1),:) - f(1:size(f,1)-1,:);
                        df(size(f,1),:) = f(size(f,1),:) - f(size(f,1)-1,:);
                    else
                        error('type not allowed');
                    end
                    
                case 'b' %backward
                    if xory == 'x'
                        df(:,2:size(f,2)) = f(:,2:size(f,2)) - f(:,1:size(f,2)-1);
                        df(:,1) = f(:,2) - f(:,1);
                    elseif xory == 'y'
                        df(2:size(f,1),:) = f(2:size(f,1),:) - f(1:size(f,1)-1,:);
                        df(1,:) = f(2,:) - f(1,:);
                    else
                        error('type not allowed');
                    end
                    
                case 'c' %central
                    switch xory
                        case 'x'
                            df(:,1) = f(:,2) - f(:,1);
                            df(:,2:size(f,2)-1) = f(:,3:size(f,2)) - f(:,1:size(f,2)-2);
                            df(:,size(f,2)) = f(:,size(f,2)) - f(:, size(f,2)-1);
                            df(:,1) = df(:,2);
                            df(:,size(f,2)) = df(:,size(f,2)-1);
                        case 'y'
                            df(1,:) = f(2,:) - f(1,:);
                            df(2:size(f,1)-1,:) = f(3:size(f,1),:) - f(1:size(f,1)-2,:);
                            df(size(f,1),:) = f(size(f,1),:) - f(size(f,1)-1,:);
                            df(1,:) = df(2,:);
                            df(size(f,1),:) = df(size(f,1)-1,:);
                        case 'xx'
                            df(:,2:size(f,2)-1) = f(:,3:size(f,2)) + f(:,1:size(f,2)-2) - 2.*f(:,2:size(f,2)-1);
                            df(:,1) = df(:,2);
                            df(:,size(f,2)) = df(:,size(f,2)-1);
                        case 'yy'
                            df(2:size(f,1)-1,:) = f(3:size(f,1),:) + f(1:size(f,1)-2,:) - 2.*f(2:size(f,1)-1,:);
                            df(1,:) = df(2,:);
                            df(size(f,1),:) = df(size(f,1)-1,:);
                        case 'xy'
                            df(2:size(f,1)-1,2:size(f,2)-1) = f(3:size(f,1),3:size(f,2)) - f(1:size(f,1)-2,3:size(f,2)) - f(3:size(f,1),1:size(f,2)-2) - f(1:size(f,1)-2,1:size(f,2)-2);
                            df(:,1) = df(:,2);
                            df(:,size(f,2)) = df(:,size(f,2)-1);
                            df(1,:) = df(2,:);
                            df(size(f,1),:) = df(size(f,1)-1,:);
                   
                    end
            end
        end
        
        function Theta = abs_2layers(app,fn, dim) % old uDenominatorXY
        
            if dim == 1
                df1p = app.finite_diff(fn, 'x', 'f');
                df2c = app.finite_diff(fn, 'y', 'c');
            elseif dim == 2
                df1p = app.finite_diff(fn, 'y', 'f');
                df2c = app.finite_diff(fn, 'x', 'c');
            end
                
            Theta = df1p.^2 + df2c.^2/4;        % protection against division by 0
            ix0 = (Theta==0);                   % crude: replace all 0's with
            replacementVal = min(Theta(~ix0));  % the next minimum found in the
            Theta(ix0) = replacementVal;        % denominator; smarter later
            Theta = 1./sqrt(Theta);
        
        end
        
        function par = parameters(type)

            switch type
                case "nregions"
                    par.nlevels = 4;
                    par.l = transpose(0:10:10*(par.nlevels-1));
                    par.lambda = ones(par.nlevels+1,1);
                    par.nphi = 1;
                    par.mu = .0035;
                    par.dt = 1;
                    par.eps = 1;
                    par.regtype = "length";
                    par.a = 30000;
                    par.truncate = true;
                    par.truncateval = -20;
                    par.showevol = false;
                    par.edgeStopType = "inner";
                    %par.edgeStopType = "all";
                    par.edgeStoptype = "extremes";
            end
            
            par.sigma = 1;
            par.nu = 0;
            par.masktype = "multicircles";
            %par.masktype = "circle";
            %par.masktype = "rectangle";          
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath([pwd '\Functions']);
            app=construct_data(app,"nregions");
            app.TextAlphaValue.Value = num2str(app.alphax1000Slider.Value);
            app.TextMuValue.Value = num2str(app.mux0001Slider.Value);
            app.TextEpsilon.Value = num2str(app.epsilonSlider.Value);
            app.data.plotsegm = app.UIAxes_2;
            app.data.par.a = app.alphax1000Slider.Value*1000;
            app.data.par.mu = app.mux0001Slider.Value*.001;
            app.data.par.eps = app.epsilonSlider.Value;
            app.data.par.sigma = str2double(app.sigmaEditField.Value);
            kmaxSpinnerValueChanged(app);
            IterationsEditFieldValueChanged(app);
            app.handlearray = [app.StatusTextArea, app.alphax1000Slider, app.TextAlphaValue, app.mux0001Slider,...
                               app.TextMuValue, app.sigmaEditField, app.epsilonSlider, app.epsilonSliderLabel, app.TextEpsilon,...
                               app.TruncatefcnCheckBox, app.alphax1000SliderLabel, app.mux0001SliderLabel, app.NumberofcontoursSpinner,...
                               app.ShowcontoursListBox, app.TotalTimeTextArea, app.RunTimeTextArea, app.ShowevolCheckBox,...
                               app.kmaxSpinner, app.IterationsEditField, app.RunButton];
            app.colormaphot = hot;
            app.colormaphot = flip(app.colormaphot(50:220,:),1);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [filename,filepath] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.oct'}, 'Select File to Open',[pwd '\Images']);
            app.data.sourcefullname = [filepath, filename];
            app.data.sourcefilename = filename;
            app.StatusTextArea.Value = 'loading';pause(app.pt);
            app.data.I = imread(app.data.sourcefullname);
            app = pre_process(app);
            app.StatusTextArea.Value = '';
            imagesc(app.UIAxes,app.data.g0);
            colormap(app.UIAxes, "gray");
            set(app.UIAxes, 'YDir','reverse','XLim',[0 size(app.data.g0,2)],'YLim',[0 size(app.data.g0,1)]);
            set(app.UIAxes_2, 'YDir','reverse','XLim',[0 size(app.data.g0,2)],'YLim',[0 size(app.data.g0,1)]);
            set(app.UIAxes_3, 'YDir','reverse','XLim',[0 size(app.data.g0,2)],'YLim',[0 size(app.data.g0,1)]);
            app = initialize(app);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StatusTextArea.Value = 'clearing';pause(app.pt);
            app = initialize(app);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            app.RunTimeTextArea.Value = '';
            app.TotalTimeTextArea.Value = '';
            tic;
            
            set(app.handlearray, 'Enable', 'Off');pause(app.pt);
            app.StatusTextArea.Value = 'running';pause(app.pt);
            app=standard(app);
            runtime=toc;
            app.RunTimeTextArea.Value = num2str(runtime);pause(app.pt);
            tic;
            app.StatusTextArea.Value = 'plotting';pause(app.pt);
            app.done=1;
            plot_results(app);
            app.TotalTimeTextArea.Value = num2str(toc+runtime);pause(app.pt);
            app.StatusTextArea.Value = 'done';pause(app.pt);
            set(app.handlearray, 'Enable', 'On');pause(app.pt);            
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            app.data.par.sigma = str2double(app.sigmaEditField.Value);
        end

        % Value changed function: alphax1000Slider
        function alphax1000SliderValueChanged(app, event)
            app.data.par.a = app.alphax1000Slider.Value*1000;
            app.TextAlphaValue.Value = num2str(app.alphax1000Slider.Value);
        end

        % Value changed function: mux0001Slider
        function mux0001SliderValueChanged(app, event)
            app.data.par.mu = app.mux0001Slider.Value*.001;   
            app.TextMuValue.Value = num2str(app.mux0001Slider.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {583, 583};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {200, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function NumberofLSFSwitchValueChanged(app, event)
            app.data.par.nphi = str2double(app.NumberofLSFSwitch.Value);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            set(app.handlearray, 'Enable', 'On');pause(app.pt);
            app = initialize(app);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
        end

        % Value changed function: NumberofcontoursSpinner
        function NumberofcontoursSpinnerValueChanged(app, event)
            if app.NumberofcontoursSpinner.Value == 1
                app.TruncatefcnCheckBox.Value = false;
                app.data.par.truncate = false;
            else
                app.TruncatefcnCheckBox.Value = true;
                app.data.par.truncate = true;
            end
            app.data.par.nlevels = app.NumberofcontoursSpinner.Value;
            app.data.par.l=transpose(0:10:10*(app.data.par.nlevels-1));
            app.data.par.lambda = ones(app.data.par.nlevels);
            app.ShowcontoursListBox.Items = {};
            app.ShowcontoursListBox.Value = {};
            for i=1:app.NumberofcontoursSpinner.Value
                app.ShowcontoursListBox.Items(i) = {num2str(i)};
                app.ShowcontoursListBox.Value(i) = {num2str(i)};
            end
        end

        % Value changed function: TruncatefcnCheckBox
        function TruncatefcnCheckBoxValueChanged(app, event)
            app.data.par.truncate = app.TruncatefcnCheckBox.Value;
        end

        % Value changed function: TextAlphaValue
        function TextAlphaValueValueChanged(app, event)
            app.alphax1000Slider.Value = str2double(app.TextAlphaValue.Value);
            app.data.par.a = app.alphax1000Slider.Value*1000;
        end

        % Value changed function: TextMuValue
        function TextMuValueValueChanged(app, event)
            app.mux0001Slider.Value = str2double(app.TextMuValue.Value);
            app.data.par.mu = app.mux0001Slider.Value*.001; 
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %[filename,filepath] = uiputfile('Select save directory');
            %app.data.destfullname = [filepath, filename];
            app.StatusTextArea.Value = 'saving';pause(app.pt);
            set(app.handlearray, 'Enable', 'Off');pause(app.pt);
            app.data.destfullname = [pwd '\results'];
            mkdir(app.data.destfullname); 
            comment = app.commentEditField.Value;
            exportgraphics(app.UIAxes_2,[app.data.destfullname '\segm_' comment '_' app.data.sourcefilename]);
            datasave=app.data;
            %hgsave(newfigure, [app.data.destfullname '\_segm_' app.data.sourcefilename '.png']);
            %saveas(app.UIAxes_2, [app.data.destfullname '\_segm_' app.data.sourcefilename], 'bmp');
            [~,name,~]=fileparts(datasave.sourcefilename);
            save([app.data.destfullname '\data_' comment '_' name], 'datasave', '-mat');
            app.commentEditField.Value = '';
            app.StatusTextArea.Value = 'saved';pause(app.pt);
            set(app.handlearray, 'Enable', 'On');pause(app.pt);
        end

        % Value changed function: epsilonSlider
        function epsilonSliderValueChanged(app, event)
            app.data.par.eps = app.epsilonSlider.Value;   
            app.TextEpsilon.Value = num2str(app.epsilonSlider.Value);
        end

        % Value changed function: TextEpsilon
        function TextEpsilonValueChanged(app, event)
            app.epsilonSlider.Value = str2double(app.TextEpsilon.Value);
            app.data.par.eps = app.epsilonSlider.Value; 
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            set(app.InstructionsTextArea, 'Visible', 'On');
            set(app.CloseButton, 'Visible', 'On', 'Enable', 'On');            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            set(app.InstructionsTextArea, 'Visible', 'off');
            set(app.CloseButton, 'Visible', 'off', 'Enable', 'off');
        end

        % Value changed function: ShowcontoursListBox
        function ShowcontoursListBoxValueChanged(app, event)
            if app.done == 1 
                set(app.UIAxes_2, 'YDir','reverse','XLim',[0 size(app.data.g0,2)],'YLim',[0 size(app.data.g0,1)]);
               
                set(app.handlearray, 'Enable', 'Off');pause(app.pt);
                app.StatusTextArea.Value = 'plotting';pause(app.pt);     
                
                value = sort(app.ShowcontoursListBox.Value);    
                colormap(app.UIAxes_2, gray);pause(app.pt);
                imagesc(app.UIAxes_2, app.data.gcurr);
                hold(app.UIAxes_2,"on");
                colorarray = round(linspace(1,size(app.colormaphot,1),size(value,2)));
                if size(value,2) == 1
                    colorarray = 1;
                elseif app.data.par.nlevels == 2
                    colorarray(1) = 1;
                    colorarray(2) = round(size(app.colormaphot,1)*.9);                    
                end
                %colorarray = {'b'; 'y'; 'r';'c'; 'm'; 'w'; 'g'};
                if app.DashedCheckBox.Value == true
                    stylearray = {'--';':';':';':';':';':';':'};
                    widtharray = [1.5; 1; 1; 1; 1; 1; 1];
                else
                    stylearray = {'-';'-';'-';'-';'-';'-';'-'};
                    widtharray = [1; .7; .7; .7; .7; .7; .7];
                end
                
                for i=1:size(value,2)
                    contour(app.UIAxes_2, app.data.phi-app.data.par.l(str2double(value(i))), [0 0], 'Color', app.colormaphot(colorarray(i),:), 'LineStyle', ...
                        stylearray{i}, 'LineWidth', widtharray(i), 'DisplayName', num2str(round(app.data.c(str2double(value(i))+1),0)));
                end
                hold(app.UIAxes_2,"off");
                lng = legend(app.UIAxes_2, 'show', 'Location', 'northeastoutside');
                %hLegend = findobj(gcf, 'Type', 'Legend');
                lng.ItemTokenSize = [10,30];
                lng.Color = [.6 .6 .6];
                lng.Title.String = ['Mean' newline 'intensities'];
                
                app.StatusTextArea.Value = 'done';pause(app.pt);
                set(app.handlearray, 'Enable', 'On');pause(app.pt);  
            end
            
        end

        % Value changed function: DashedCheckBox
        function DashedCheckBoxValueChanged(app, event)
            ShowcontoursListBoxValueChanged(app);
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            set(app.UIAxes_2, 'YDir','reverse','XLim',[0 size(app.data.gcurr,2)],'YLim',[0 size(app.data.gcurr,1)]);
            %handlearray = [app.StatusTextArea, app.alphax1000Slider, app.TextAlphaValue, app.mux0001Slider,...
            %    app.TextMuValue, app.sigmaEditField, app.epsilonSlider, app.epsilonSliderLabel, app.TextEpsilon,...
            %    app.TruncatefcnCheckBox, app.alphax1000SliderLabel, app.mux0001SliderLabel, app.NumberofcontoursSpinner,...
            %    app.ShowcontoursListBox, app.TotalTimeTextArea, app.RunTimeTextArea];
            %set(handlearray, 'Enable', 'Off');pause(app.pt);
            app.StatusTextArea.Value = 'plotting';pause(app.pt);     
                    
            value = sort(app.ShowcontoursListBox.Value); 
            
            imagesc(app.UIAxes_2, app.data.gcurr);
            hold(app.UIAxes_2,"on");
            colormap(app.UIAxes_2, gray);
            colorarray = {'b'; 'y'; 'r';'c'; 'm'; 'w'; 'g'};
            if app.DashedCheckBox.Value == true
                stylearray = {'--';':';':';':';':';':';':'};
                widtharray = [1.5; 1.2; 1.2; 1; 1; 1; 1];
            else
                stylearray = {'-';'-';'-';'-';'-';'-';'-'};
                widtharray = [1; .7; .7; .7; .7; .7; .7];
            end
            
            for i=1:size(value,2)
                contour(app.UIAxes_2, app.data.phi-app.data.par.l(str2double(value(i))), [0 0], 'Color', colorarray{i}, 'LineStyle', ...
                    stylearray{i}, 'LineWidth', widtharray(i), 'DisplayName', num2str(round(app.data.c(str2double(value(i))+1),0)));
            end
            hold(app.UIAxes_2,"off");
            
            app.StatusTextArea.Value = 'done';pause(app.pt);
            %set(handlearray, 'Enable', 'On');pause(app.pt);  
        end

        % Value changed function: kmaxSpinner
        function kmaxSpinnerValueChanged(app, event)
            app.data.par.kMax = app.kmaxSpinner.Value;
            IterationsEditFieldValueChanged(app);
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            num = app.IterationsEditField.Value;
            num = textscan(num, '%f', 'Delimiter',',' );
            app.data.it.stepsvec = permute(num{1}, [1,2]);
        end

        % Callback function
        function StopButtonPushed(app, event)
            set(app.handlearray, 'Enable', 'On');pause(app.pt);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveMenuSelected(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ResetMenu = uimenu(app.UIFigure);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.alphax1000SliderLabel = uilabel(app.LeftPanel);
            app.alphax1000SliderLabel.Text = 'alpha x 1000';
            app.alphax1000Slider = uislider(app.LeftPanel);
            app.alphax1000Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.alphax1000Slider.ValueChangedFcn = createCallbackFcn(app, @alphax1000SliderValueChanged, true);
            app.alphax1000Slider.Value = 40;
            app.mux0001SliderLabel = uilabel(app.LeftPanel);
            app.mux0001SliderLabel.Text = 'mu x 0.001';
            app.mux0001Slider = uislider(app.LeftPanel);
            app.mux0001Slider.Limits = [0 50];
            app.mux0001Slider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.mux0001Slider.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.mux0001Slider.ValueChangedFcn = createCallbackFcn(app, @mux0001SliderValueChanged, true);
            app.mux0001Slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 11 12 13 14 15 16 17 18 19 20];
            app.mux0001Slider.Value = 4;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.sigmaEditFieldLabel = uilabel(app.LeftPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.LeftPanel, 'text');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Value = '1';
            app.NumberofcontoursSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofcontoursSpinnerLabel.Text = {'Number '; 'of contours'};
            app.NumberofcontoursSpinner = uispinner(app.LeftPanel);
            app.NumberofcontoursSpinner.Limits = [1 7];
            app.NumberofcontoursSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofcontoursSpinnerValueChanged, true);
            app.NumberofcontoursSpinner.Value = 4;
            app.TruncatefcnCheckBox = uicheckbox(app.LeftPanel);
            app.TruncatefcnCheckBox.ValueChangedFcn = createCallbackFcn(app, @TruncatefcnCheckBoxValueChanged, true);
            app.TruncatefcnCheckBox.Text = 'Truncate fcn';
            app.TruncatefcnCheckBox.Value = true;
            app.TextAlphaValue = uitextarea(app.LeftPanel);
            app.TextAlphaValue.ValueChangedFcn = createCallbackFcn(app, @TextAlphaValueValueChanged, true);
            app.TextMuValue = uitextarea(app.LeftPanel);
            app.TextMuValue.ValueChangedFcn = createCallbackFcn(app, @TextMuValueValueChanged, true);
            app.epsilonSliderLabel = uilabel(app.LeftPanel);
            app.epsilonSliderLabel.Text = 'epsilon';
            app.epsilonSlider = uislider(app.LeftPanel);
            app.epsilonSlider.Limits = [0.5 2.5];
            app.epsilonSlider.MajorTicks = [0.5 1 1.5 2 2.5];
            app.epsilonSlider.ValueChangedFcn = createCallbackFcn(app, @epsilonSliderValueChanged, true);
            app.epsilonSlider.Value = 1;
            app.TextEpsilon = uitextarea(app.LeftPanel);
            app.TextEpsilon.ValueChangedFcn = createCallbackFcn(app, @TextEpsilonValueChanged, true);
            app.ShowcontoursLabel = uilabel(app.LeftPanel);
            app.ShowcontoursLabel.Text = {'Show'; 'contours'};
            app.ShowcontoursListBox = uilistbox(app.LeftPanel);
            app.ShowcontoursListBox.Items = {'1', '2', '3', '4'};
            app.ShowcontoursListBox.Multiselect = 'on';
            app.ShowcontoursListBox.ValueChangedFcn = createCallbackFcn(app, @ShowcontoursListBoxValueChanged, true);
            app.ShowcontoursListBox.Value = {'1', '2', '3', '4'};
            app.DashedCheckBox = uicheckbox(app.LeftPanel);
            app.DashedCheckBox.ValueChangedFcn = createCallbackFcn(app, @DashedCheckBoxValueChanged, true);
            app.DashedCheckBox.Text = 'Dashed';
            app.DashedCheckBox.Value = true;
            app.ShowevolCheckBox = uicheckbox(app.LeftPanel);
            app.ShowevolCheckBox.Text = 'Show evol';
            app.kmaxSpinnerLabel = uilabel(app.LeftPanel);
            app.kmaxSpinnerLabel.Text = 'kmax';
            app.kmaxSpinner = uispinner(app.LeftPanel);
            app.kmaxSpinner.Limits = [1 4];
            app.kmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @kmaxSpinnerValueChanged, true);
            app.kmaxSpinner.Value = 3;
            app.IterationsEditFieldLabel = uilabel(app.LeftPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.LeftPanel, 'text');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = '2,2,50,100,50';
            app.commentEditFieldLabel = uilabel(app.LeftPanel);
            app.commentEditFieldLabel.Text = 'comment';
            app.commentEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Segmentation')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.YDir = 'reverse';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Approximation')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.YDir = 'reverse';
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Level Set Function')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.YDir = 'reverse';
            app.RunTimeTextAreaLabel = uilabel(app.RightPanel);
            app.RunTimeTextAreaLabel.Text = 'Run Time';
            app.RunTimeTextArea = uitextarea(app.RightPanel);
            app.RunTimeTextArea.Editable = 'off';
            app.StatusTextAreaLabel = uilabel(app.RightPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.RightPanel);
            app.StatusTextArea.Editable = 'off';
            app.InstructionsTextArea = uitextarea(app.RightPanel);
            app.InstructionsTextArea.Visible = 'off';
            app.InstructionsTextArea.Value = {'                                                                           INSTRUCTIONS'; 'Menu:'; 'Load - load image from file'; 'Save - save current segmentation figure and data sctructure to ''\results'' folder'; 'Reset - reset segmentation results'; ''; 'Parameters:'; 'dt - iteration step: decrease if solutions gets too unstable, raise to get faster results'; 'number of contours: number of divisions in temperature regions'; 'alpha - weight parameter of the gradient in inner contour: increase to make inner countour stick more strongly to the highest intensity regions, but too high causes instability'; 'mu - weight of countour smoothness: increase to get smoother contour, decrease to give more significance to the intensities of each region'; 'epsilon - regularization term of heaviside and dirac functions: treat as a sort of "length of the borders": increase if response becomes too unstable, but also so that zones have a stronger influence on one another, too high will cause poor segmentation since areas will get too mixed up, too low results in poor regularization and high instability'; 'Truncate fcn - tuncates level set function to minimum value of -20. Tick to remove "black dots" in segmentation caused by "low spikes" in LSF, de-tick if wierd "line" elements appear in segmentation'; ''; 'IMPORTANT - de-tick "truncate fcn" when doing segmentation with only 2 contours'; ''; 'Show Contours: select which contour are relevant: 1 is the outermost contour, the hightest number is the innermost contour'; 'Use ctrl+click to select or de-select multiple contours'; ''; 'Show evol: show evolution of contours at every step'; ''; 'Dashed: Check to get dashed lines instead of continuous'; ''; 'Run: run default number of iterations'};
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            app.TotalTimeTextAreaLabel = uilabel(app.RightPanel);
            app.TotalTimeTextAreaLabel.Text = 'Total Time';
            app.TotalTimeTextArea = uitextarea(app.RightPanel);
            app.TotalTimeTextArea.Editable = 'off';
            app.MeanIntensititesTextAreaLabel = uilabel(app.UIFigure);
            app.MeanIntensititesTextAreaLabel.Text = {'Mean'; 'Intensitites'};
            app.MeanIntensititesTextArea = uitextarea(app.UIFigure);
            app.MeanIntensititesTextArea.Editable = 'off';
            app.MeanIntensititesTextArea.Enable = 'off';
            app.MeanIntensititesTextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end